classdef LoomoDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConnectionPanel                matlab.ui.container.Panel
        ConnectButton                  matlab.ui.control.StateButton
        ConnectionLamp                 matlab.ui.control.Lamp
        LoomoIpLabel                   matlab.ui.control.Label
        LoomoIpEditField               matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        PortEditFieldLabel             matlab.ui.control.Label
        PortEditField                  matlab.ui.control.NumericEditField
        VisionPanel                    matlab.ui.container.Panel
        ColorCheckBox                  matlab.ui.control.CheckBox
        DepthCheckBox                  matlab.ui.control.CheckBox
        ColorLargeCheckBox             matlab.ui.control.CheckBox
        PointcloudslowCheckBox         matlab.ui.control.CheckBox
        ResponciveCheckBox             matlab.ui.control.CheckBox
        EnableVisionButton             matlab.ui.control.StateButton
        ActiveLampLabel                matlab.ui.control.Label
        ActiveLamp                     matlab.ui.control.Lamp
        DrivePanel                     matlab.ui.container.Panel
        EnabledriveSwitchLabel         matlab.ui.control.Label
        EnabledriveSwitch              matlab.ui.control.Switch
        DriveLamp                      matlab.ui.control.Lamp
        VelocityGaugeLabel             matlab.ui.control.Label
        VelocityGauge                  matlab.ui.control.SemicircularGauge
        UltrasonicandIRPanel           matlab.ui.container.Panel
        LeftGaugeLabel                 matlab.ui.control.Label
        LeftGauge                      matlab.ui.control.LinearGauge
        FrontGaugeLabel                matlab.ui.control.Label
        FrontGauge                     matlab.ui.control.LinearGauge
        RightGaugeLabel                matlab.ui.control.Label
        RightGauge                     matlab.ui.control.LinearGauge
        TurnRateGaugeLabel             matlab.ui.control.Label
        TurnRateGauge                  matlab.ui.control.SemicircularGauge
        Velocitypulse700msPanel        matlab.ui.container.Panel
        LinearVelocityEditFieldLabel   matlab.ui.control.Label
        LinearVelocityEditField        matlab.ui.control.NumericEditField
        AngularVelocityEditFieldLabel  matlab.ui.control.Label
        AngularVelocityEditField       matlab.ui.control.NumericEditField
        SendVelocityButton             matlab.ui.control.Button
        SetheadpositionPanel           matlab.ui.container.Panel
        PitchEditFieldLabel            matlab.ui.control.Label
        PitchEditField                 matlab.ui.control.NumericEditField
        YawEditFieldLabel              matlab.ui.control.Label
        YawEditField                   matlab.ui.control.NumericEditField
        SendHeadPositionButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        loomo                   Loomo % Loomo connection handle
        isConnected = false           % Indicate connection status
        MemoryTimer                   % Timer handle
        
    end
    
    methods (Access = private)
        
        function changeDriveState(app,on)
           app.loomo.enableDrive(on)
           app.SendVelocityButton.Enable = on;
        end
   
        function on = changeVisionState(app, on)
            color = app.ColorCheckBox.Value;
            colorLarge = app.ColorLargeCheckBox.Value;
            depth = app.DepthCheckBox.Value;
            
            anyOn = any([color,colorLarge, depth]);
            on = on && anyOn;
            
            if on
                app.loomo.enableVision(color,colorLarge,depth)
                app.ActiveLamp.Color = [0,1,0];    
            else
                app.loomo.enableVision(false,false,false)
                app.ActiveLamp.Color = [1,0,0];
            end
            app.ColorCheckBox.Enable = ~on;
            app.ColorLargeCheckBox.Enable = ~on;
            app.DepthCheckBox.Enable = ~on;
            app.ResponciveCheckBox.Enable = ~on;
            app.PointcloudslowCheckBox.Enable = ~on;
        end
        
        function setResponciveVision(app)
            value = app.ResponciveCheckBox.Value;
            if value
                app.ColorCheckBox.Value = true;
                app.DepthCheckBox.Value = false;
                app.ColorLargeCheckBox.Value = false;
                
                app.PointcloudslowCheckBox.Value = false;
            end
            app.ColorCheckBox.Enable = ~value;
            app.ColorLargeCheckBox.Enable = ~value;
            app.DepthCheckBox.Enable = ~value;
        end
        
        function setPointCloudVision(app)
            value = app.PointcloudslowCheckBox.Value;
            if value
                app.ColorCheckBox.Value = true;
                app.DepthCheckBox.Value = true;
                app.ColorLargeCheckBox.Value = false;
                
                app.ResponciveCheckBox.Value = false;
            end
            app.ColorCheckBox.Enable = ~value;
            app.ColorLargeCheckBox.Enable = ~value;
            app.DepthCheckBox.Enable = ~value;
        end
        
        function result = connectToLoomo(app)
            result = false;
            try
                app.loomo.connect();
                app.isConnected = true;
                
                app.EnabledriveSwitch.Enable = true;
                app.EnableVisionButton.Enable = true;
                app.SendHeadPositionButton.Enable = true;
                app.ConnectionLamp.Color = [0,1,0];
                app.ConnectButton.Text = "Disconnect";
                
                result = true;
            catch ME
                warning("Connection to loomo failed")
            end
            %start(app.MemoryTimer)
        end
    
        function disconnectFromLoomo(app)
            try
                %stop(app.MemoryTimer);
                %delete(app.MemoryTimer);
                app.changeDriveState(false);
                app.changeVisionState(false);
                app.loomo.disconnect();
                
                app.isConnected = false;
                app.ConnectionLamp.Color = [1,0,0];
                app.ConnectButton.Text = "Connect";
            catch ME
                %warning(ME.message)
            end
            app.EnabledriveSwitch.Enable = false;
            app.SendVelocityButton.Enable = false;
            app.EnableVisionButton.Enable = false;
            app.SendHeadPositionButton.Enable = false;
        end
    
        function mainLoop(app)
            app.readVelocity();
        end
    
        function readVelocity(app)
            vel = app.loomo.getPose2D()
            app.VelocityGauge.Value = vel.vl;
            app.TurnRateGauge.Value = vel.av;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                ip = app.LoomoIpEditField.Value;
                port = app.PortEditField.Value;
                app.loomo = Loomo(ip,port);
                
                % Create timer object
%             app.MemoryTimer = timer(...
%                 'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
%                 'Period', 0.5, ...                     % Period is 1 second
%                 'BusyMode', 'queue',...              % Queue timer callbacks when busy
%                 'TimerFcn', @app.mainLoop);          % Specify callback function
        end

        % Value changed function: EnableVisionButton
        function EnableVisionButtonValueChanged(app, event)
            value = app.EnableVisionButton.Value;
            
            if app.isConnected
               app.EnableVisionButton.Value = app.changeVisionState(value);
            else
                app.EnableVisionButton.Value = false;
                warning("Loomo is not connected: Could not activate vision")
            end
            
            
        end

        % Value changed function: ResponciveCheckBox
        function ResponciveCheckBoxValueChanged(app, event)
            app.setResponciveVision()
        end

        % Value changed function: PointcloudslowCheckBox
        function PointcloudslowCheckBoxValueChanged(app, event)
            app.setPointCloudVision()
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            
            if value
                app.ConnectButton.Value = app.connectToLoomo();
            else
                app.disconnectFromLoomo();
            end
        end

        % Value changed function: LoomoIpEditField
        function LoomoIpEditFieldValueChanged(app, event)
            value = app.LoomoIpEditField.Value;
            app.loomo.loomoIp = value;
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            value = app.PortEditField.Value;
            app.loomo.loomoPort = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.disconnectFromLoomo()
            delete(app)
        end

        % Value changed function: EnabledriveSwitch
        function EnabledriveSwitchValueChanged(app, event)
            value = app.EnabledriveSwitch.Value;
            value = strcmp(value,"On");
            app.changeDriveState(value);
        end

        % Button pushed function: SendVelocityButton
        function SendVelocityButtonPushed(app, event)
            linVel = app.LinearVelocityEditField.Value;
            angVel = app.AngularVelocityEditField.Value;
            
            app.loomo.setVelocity(linVel, angVel);
        end

        % Button pushed function: SendHeadPositionButton
        function SendHeadPositionButtonPushed(app, event)
            pitch = app.PitchEditField.Value*pi/180;
            yaw = app.YawEditField.Value*pi/180;
            
            app.loomo.setHeadPosition(yaw,pitch)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectionLamp = uilamp(app.ConnectionPanel);
            app.LoomoIpLabel = uilabel(app.ConnectionPanel);
            app.LoomoIpLabel.Text = 'Loomo Ip';
            app.LoomoIpEditField = uieditfield(app.ConnectionPanel, 'text');
            app.LoomoIpEditField.ValueChangedFcn = createCallbackFcn(app, @LoomoIpEditFieldValueChanged, true);
            app.LoomoIpEditField.Tooltip = {'Ip adress of loomo'};
            app.LoomoIpEditField.Value = '192.168.137.84';
            app.Label = uilabel(app.ConnectionPanel);
            app.Label.Text = '/';
            app.PortEditFieldLabel = uilabel(app.ConnectionPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.ConnectionPanel, 'numeric');
            app.PortEditField.Limits = [0 65535];
            app.PortEditField.RoundFractionalValues = 'on';
            app.PortEditField.ValueDisplayFormat = '%11d';
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 1337;
            app.VisionPanel = uipanel(app.UIFigure);
            app.VisionPanel.Title = 'Vision';
            app.ColorCheckBox = uicheckbox(app.VisionPanel);
            app.ColorCheckBox.Text = 'Color';
            app.DepthCheckBox = uicheckbox(app.VisionPanel);
            app.DepthCheckBox.Text = 'Depth';
            app.ColorLargeCheckBox = uicheckbox(app.VisionPanel);
            app.ColorLargeCheckBox.Text = 'Color Large';
            app.PointcloudslowCheckBox = uicheckbox(app.VisionPanel);
            app.PointcloudslowCheckBox.ValueChangedFcn = createCallbackFcn(app, @PointcloudslowCheckBoxValueChanged, true);
            app.PointcloudslowCheckBox.Text = 'Point cloud (slow)';
            app.ResponciveCheckBox = uicheckbox(app.VisionPanel);
            app.ResponciveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResponciveCheckBoxValueChanged, true);
            app.ResponciveCheckBox.Text = 'Responcive';
            app.EnableVisionButton = uibutton(app.VisionPanel, 'state');
            app.EnableVisionButton.ValueChangedFcn = createCallbackFcn(app, @EnableVisionButtonValueChanged, true);
            app.EnableVisionButton.Enable = 'off';
            app.EnableVisionButton.Text = 'Enable Vision';
            app.ActiveLampLabel = uilabel(app.VisionPanel);
            app.ActiveLampLabel.Text = 'Active';
            app.ActiveLamp = uilamp(app.VisionPanel);
            app.DrivePanel = uipanel(app.UIFigure);
            app.DrivePanel.Title = 'Drive';
            app.EnabledriveSwitchLabel = uilabel(app.DrivePanel);
            app.EnabledriveSwitchLabel.Text = 'Enable drive';
            app.EnabledriveSwitch = uiswitch(app.DrivePanel, 'slider');
            app.EnabledriveSwitch.ValueChangedFcn = createCallbackFcn(app, @EnabledriveSwitchValueChanged, true);
            app.EnabledriveSwitch.Enable = 'off';
            app.DriveLamp = uilamp(app.DrivePanel);
            app.VelocityGaugeLabel = uilabel(app.UIFigure);
            app.VelocityGaugeLabel.Text = 'Velocity';
            app.VelocityGauge = uigauge(app.UIFigure, 'semicircular');
            app.VelocityGauge.Limits = [-2 2];
            app.UltrasonicandIRPanel = uipanel(app.UIFigure);
            app.UltrasonicandIRPanel.Title = 'Ultrasonic and IR';
            app.LeftGaugeLabel = uilabel(app.UltrasonicandIRPanel);
            app.LeftGaugeLabel.Text = 'Left';
            app.LeftGauge = uigauge(app.UltrasonicandIRPanel, 'linear');
            app.LeftGauge.Limits = [0.3 1.2];
            app.FrontGaugeLabel = uilabel(app.UltrasonicandIRPanel);
            app.FrontGaugeLabel.Text = 'Front';
            app.FrontGauge = uigauge(app.UltrasonicandIRPanel, 'linear');
            app.FrontGauge.Limits = [0.3 1.2];
            app.RightGaugeLabel = uilabel(app.UltrasonicandIRPanel);
            app.RightGaugeLabel.Text = 'Right';
            app.RightGauge = uigauge(app.UltrasonicandIRPanel, 'linear');
            app.RightGauge.Limits = [0.3 1.2];
            app.TurnRateGaugeLabel = uilabel(app.UIFigure);
            app.TurnRateGaugeLabel.Text = 'Turn Rate';
            app.TurnRateGauge = uigauge(app.UIFigure, 'semicircular');
            app.TurnRateGauge.Limits = [-2 2];
            app.Velocitypulse700msPanel = uipanel(app.UIFigure);
            app.Velocitypulse700msPanel.Title = 'Velocity pulse (700ms)';
            app.LinearVelocityEditFieldLabel = uilabel(app.Velocitypulse700msPanel);
            app.LinearVelocityEditFieldLabel.Text = 'Linear Velocity';
            app.LinearVelocityEditField = uieditfield(app.Velocitypulse700msPanel, 'numeric');
            app.LinearVelocityEditField.Limits = [-1 1];
            app.LinearVelocityEditField.ValueDisplayFormat = '%11.4g m/s';
            app.AngularVelocityEditFieldLabel = uilabel(app.Velocitypulse700msPanel);
            app.AngularVelocityEditFieldLabel.Text = 'Angular Velocity';
            app.AngularVelocityEditField = uieditfield(app.Velocitypulse700msPanel, 'numeric');
            app.AngularVelocityEditField.Limits = [-1.5 1.5];
            app.AngularVelocityEditField.ValueDisplayFormat = '%11.2g rad/s';
            app.SendVelocityButton = uibutton(app.Velocitypulse700msPanel, 'push');
            app.SendVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @SendVelocityButtonPushed, true);
            app.SendVelocityButton.Enable = 'off';
            app.SendVelocityButton.Text = 'Send';
            app.SetheadpositionPanel = uipanel(app.UIFigure);
            app.SetheadpositionPanel.Title = 'Set head position';
            app.PitchEditFieldLabel = uilabel(app.SetheadpositionPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.SetheadpositionPanel, 'numeric');
            app.PitchEditField.Limits = [-90 180];
            app.PitchEditField.RoundFractionalValues = 'on';
            app.PitchEditField.ValueDisplayFormat = '%11g Deg';
            app.YawEditFieldLabel = uilabel(app.SetheadpositionPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.SetheadpositionPanel, 'numeric');
            app.YawEditField.Limits = [-150 150];
            app.YawEditField.RoundFractionalValues = 'on';
            app.YawEditField.ValueDisplayFormat = '%11.4g Deg';
            app.SendHeadPositionButton = uibutton(app.SetheadpositionPanel, 'push');
            app.SendHeadPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SendHeadPositionButtonPushed, true);
            app.SendHeadPositionButton.Enable = 'off';
            app.SendHeadPositionButton.Text = 'Send';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoomoDemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end